classdef ML_Opt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ML_Photonics_Designer           matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        DefineTargetTab                 matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        CenterX0EditField               matlab.ui.control.NumericEditField
        CenterX0EditFieldLabel          matlab.ui.control.Label
        FWHMEditField                   matlab.ui.control.NumericEditField
        FWHMEditFieldLabel              matlab.ui.control.Label
        LorentzianCalculatorLabel       matlab.ui.control.Label
        ImportSpectrumfromExcelLabel    matlab.ui.control.Label
        Image_Lorentzian                matlab.ui.control.Image
        Switch                          matlab.ui.control.Switch
        TargetDefinitionOptionsLabel    matlab.ui.control.Label
        ImportSpectrumButton            matlab.ui.control.Button
        GenerateDesignTab               matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        IndexEditField                  matlab.ui.control.NumericEditField
        IndexEditFieldLabel             matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        PlotElectricFieldLabel          matlab.ui.control.Label
        SimulateButton                  matlab.ui.control.Button
        SimulateDesigninLumericalLabel  matlab.ui.control.Label
        PassTargetintoGeneratorLabel    matlab.ui.control.Label
        SetGeneratorPathLabel           matlab.ui.control.Label
        GenerationandSimulateDesignLabel  matlab.ui.control.Label
        ImportGeneratorButton           matlab.ui.control.Button
        GenerateDesignButton            matlab.ui.control.Button
        OptimizeTab                     matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        RunButton                       matlab.ui.control.Button
        Polarizations1p2EditField       matlab.ui.control.NumericEditField
        Polarizations1p2EditFieldLabel  matlab.ui.control.Label
        IterationsEditField             matlab.ui.control.NumericEditField
        IterationsEditFieldLabel        matlab.ui.control.Label
        TargetmEditField                matlab.ui.control.NumericEditField
        TargetmEditFieldLabel           matlab.ui.control.Label
        AdjointOptimizationLabel        matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        GridLayout1                     matlab.ui.container.GridLayout
        ThicknessLabelValue             matlab.ui.control.NumericEditField
        MaterialLabelValue              matlab.ui.control.NumericEditField
        ThicknessnmLabel                matlab.ui.control.Label
        MaterialLabel                   matlab.ui.control.Label
        ImageGAN                        matlab.ui.control.Image
        TargetSpectrumPlotLabel         matlab.ui.control.Label
        TargetSpectrumPointsLabel       matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        MLGeneratedStructureLabel       matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        EMFieldProfileLabel             matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        PreferencesMenu                 matlab.ui.container.Menu
        SetPythonPathMenu               matlab.ui.container.Menu
        SetLumericalWorkingDirectoryMenu  matlab.ui.container.Menu
        SetAPIPathMenu                  matlab.ui.container.Menu
        HelpDocumentationMenu           matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        file_csv;
        path_csv;
        file_m;
        path_m;
        file_netG;
        path_netG;
        x;
        y;
        fileFDFD_csv;
        pathFDFD_csv;
        x2;
        y2;
        fwhm;
        x0;
        pyout;
        datafile;
        genimported;
        rgbImage;
        props;
        sim_absorption;
        index;
        electric;
        DialogApp;
    end
    
    properties (Access = public)
        py_path
        folder_path
        api_path
    end
    
    %For debugging, do this to remove preference: rmpref('ganapppref')
    methods (Access = public)        
        function updatePy(app, path)
            app.py_path = strrep(path, '\', '/');
            setpref('ganapppref', 'py_path', app.py_path);
        end       
        function updateFolder(app, path)
            app.folder_path = strrep(path, '\', '/');
            if app.folder_path(end) ~= '/'
                app.folder_path = append(app.folder_path,'/');
            end
            setpref('ganapppref', 'folder_path', app.folder_path);
        end
        function updateApi(app, path)
            app.api_path = strrep(path, '\', '/');
            setpref('ganapppref', 'api_path', app.api_path);
        end               
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Check preferences on startup.
            if ispref('ganapppref','py_path')
                app.py_path = getpref('ganapppref', 'py_path');
            end
            if ispref('ganapppref','folder_path')
                app.folder_path = getpref('ganapppref', 'folder_path');
            end
            if ispref('ganapppref','api_path')
                app.api_path = getpref('ganapppref', 'api_path');
            end

            app.ML_Photonics_Designer.Icon = 'Icon.PNG';    
            app.Image_Lorentzian.Enable = 'off';
            app.FWHMEditField.Enable = 'off';            
            app.CenterX0EditField.Enable = 'off';
            app.LorentzianCalculatorLabel.Enable = 'off';
            
            app.SetGeneratorPathLabel.Enable = 'off';
            app.ImportGeneratorButton.Enable = 'off';
            app.GenerateDesignButton.Enable = 'off';
            app.PassTargetintoGeneratorLabel.Enable = 'off';
            app.SimulateButton.Enable = 'off';
            app.SimulateDesigninLumericalLabel.Enable = 'off';
            
            app.PlotElectricFieldLabel.Enable = 'off';
            app.PlotButton.Enable = 'off';
            app.IndexEditField.Enable = 'off';
            app.IndexEditFieldLabel.Enable = 'off';
            app.UIAxes3.Visible = 'off';
            
            app.fwhm = 0.75;
            app.x0 = 6;
            app.genimported = 0;
            
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            GuiWidth = 832;
            GuiHeight = 477;
            left = screenWidth*0.5;
            bottom = screenHeight*0.5;
            app.ML_Photonics_Designer.Position = [left-GuiWidth/2 bottom-GuiHeight/2 GuiWidth GuiHeight]; 
            drawnow;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ML_Photonics_Designer.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {477, 477};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {270, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: SetPythonPathMenu
        function SetPythonPathMenuSelected(app, event)
            % Open the options dialog and pass inputs
            app.DialogApp = SetPythonPath(app);
        end

        % Menu selected function: SetLumericalWorkingDirectoryMenu
        function SetLumericalWorkingDirectoryMenuSelected(app, event)
            app.DialogApp = SetFolderPath(app);
        end

        % Menu selected function: SetAPIPathMenu
        function SetAPIPathMenuSelected(app, event)
            app.DialogApp = SetApiPath(app);            
        end

        % Menu selected function: HelpDocumentationMenu
        function HelpDocumentationMenuSelected(app, event)
            web('https://github.com/Raman-Lab-UCLA')
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes3)            
            colorbar(app.UIAxes3, 'off');
            value = app.Switch.Value;
            
            if contains('Lorentz. Calc.',value)
                app.FWHMEditField.Enable = 'on';         
                app.CenterX0EditField.Enable = 'on';  
                app.Image_Lorentzian.Enable = 'on';    
                app.LorentzianCalculatorLabel.Enable = 'on';
                app.ImportSpectrumButton.Enable = 'off';   
                app.ImportSpectrumfromExcelLabel.Enable = 'off'; 
                
                app.x=4:0.01:11.99;
                app.y=zeros(1,800);
                for i = 1:800
                    app.y(i)=(1/pi)*(app.fwhm*0.5)/((app.x(i)-app.x0)^2+(0.5*app.fwhm)^2);
                end
                app.UITable.Data = [app.x.',app.y.'];
                plot(app.UIAxes,app.x,app.y,'LineWidth',3);
                legend(app.UIAxes, 'Target');
                
                app.SetGeneratorPathLabel.Enable = 'on';
                app.ImportGeneratorButton.Enable = 'on';
            end
            if contains('Import Excel',value)
                app.FWHMEditField.Enable = 'off';          
                app.CenterX0EditField.Enable = 'off';
                app.Image_Lorentzian.Enable = 'off';     
                app.LorentzianCalculatorLabel.Enable = 'off';                
                app.ImportSpectrumButton.Enable = 'on';       
                app.ImportSpectrumfromExcelLabel.Enable = 'on';
                
                app.UITable.Data = [];
                cla(app.UIAxes);
                
                if app.genimported == 0
                    app.SetGeneratorPathLabel.Enable = 'off';
                    app.ImportGeneratorButton.Enable = 'off';
                end
            end
            
            app.ImageGAN.Visible = 'off';
            app.Image.Visible = 'on';
            app.Image2.Visible = 'on';
            app.UIAxes3.Visible = 'off';
            app.MaterialLabel.Visible = 'off';
            app.MaterialLabelValue.Visible = 'off';
            app.ThicknessnmLabel.Visible = 'off';
            app.ThicknessLabelValue.Visible = 'off';
            app.SimulateButton.Enable = 'off';
            app.SimulateDesigninLumericalLabel.Enable = 'off';
            app.PlotElectricFieldLabel.Enable = 'off';
            app.PlotButton.Enable = 'off';
            app.IndexEditField.Enable = 'off';
            app.IndexEditFieldLabel.Enable = 'off';
            app.MaterialLabel.Text = 'Material Property (ω or n)';
        end

        % Value changed function: FWHMEditField
        function FWHMEditFieldValueChanged(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes3)            
            colorbar(app.UIAxes3, 'off');

            app.fwhm = app.FWHMEditField.Value;
            
            app.x=4:0.01:11.99;
            app.y=zeros(1,800);
            for i = 1:800
                app.y(i)=(1/pi)*(app.fwhm*0.5)/((app.x(i)-app.x0)^2+(0.5*app.fwhm)^2);
            end
            app.UITable.Data = [app.x.',app.y.'];
            plot(app.UIAxes,app.x,app.y,'LineWidth',3);
            legend(app.UIAxes, 'Target');
            
            app.SetGeneratorPathLabel.Enable = 'on';
            app.ImportGeneratorButton.Enable = 'on';
            
            app.ImageGAN.Visible = 'off';
            app.Image.Visible = 'on';
            app.Image2.Visible = 'on';
            app.UIAxes3.Visible = 'off';
            app.MaterialLabel.Visible = 'off';
            app.MaterialLabelValue.Visible = 'off';
            app.ThicknessnmLabel.Visible = 'off';
            app.ThicknessLabelValue.Visible = 'off';
            app.SimulateButton.Enable = 'off';
            app.SimulateDesigninLumericalLabel.Enable = 'off';
            app.PlotElectricFieldLabel.Enable = 'off';
            app.PlotButton.Enable = 'off';
            app.IndexEditField.Enable = 'off';
            app.IndexEditFieldLabel.Enable = 'off';
            app.MaterialLabel.Text = 'Material Property (ω or n)';
        end

        % Value changed function: CenterX0EditField
        function CenterX0EditFieldValueChanged(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes3)            
            colorbar(app.UIAxes3, 'off');

            app.x0 = app.CenterX0EditField.Value;
            
            app.x=4:0.01:11.99;
            app.y=zeros(1,800);
            for i = 1:800
                app.y(i)=(1/pi)*(app.fwhm*0.5)/((app.x(i)-app.x0)^2+(0.5*app.fwhm)^2);
            end
            app.UITable.Data = [app.x.',app.y.'];
            plot(app.UIAxes,app.x,app.y,'LineWidth',3);
            legend(app.UIAxes, 'Target');
            
            app.SetGeneratorPathLabel.Enable = 'on';
            app.ImportGeneratorButton.Enable = 'on';
            
            app.ImageGAN.Visible = 'off';
            app.Image.Visible = 'on';
            app.Image2.Visible = 'on';
            app.UIAxes3.Visible = 'off';
            app.MaterialLabel.Visible = 'off';
            app.MaterialLabelValue.Visible = 'off';
            app.ThicknessnmLabel.Visible = 'off';
            app.ThicknessLabelValue.Visible = 'off';
            app.SimulateButton.Enable = 'off';
            app.SimulateDesigninLumericalLabel.Enable = 'off';
            app.PlotElectricFieldLabel.Enable = 'off';
            app.PlotButton.Enable = 'off';
            app.IndexEditField.Enable = 'off';
            app.IndexEditFieldLabel.Enable = 'off';
            app.MaterialLabel.Text = 'Material Property (ω or n)';
        end

        % Button pushed function: ImportSpectrumButton
        function ImportSpectrumButtonPushed(app, event)
             %Clear Image
            app.Image2.Visible = 'on';
            app.UIAxes3.Visible = 'off';
            cla(app.UIAxes)
            cla(app.UIAxes3)            
            colorbar(app.UIAxes3, 'off');
            
            [app.file_csv,app.path_csv] = uigetfile('*.csv');
            if app.file_csv==0
                % user pressed cancel
                return
            end       
            rawData=readtable(strcat(app.path_csv,app.file_csv));
            app.UITable.Data = rawData(:,1:2);
            app.x=table2array(app.UITable.Data(:,1));
            app.y=table2array(app.UITable.Data(:,2));
            plot(app.UIAxes,app.x,app.y,'LineWidth',3);
            legend(app.UIAxes, 'Target');
            
            app.SetGeneratorPathLabel.Enable = 'on';
            app.ImportGeneratorButton.Enable = 'on';
            
            app.ImageGAN.Visible = 'off';
            app.Image.Visible = 'on';
            app.MaterialLabel.Visible = 'off';
            app.MaterialLabelValue.Visible = 'off';
            app.ThicknessnmLabel.Visible = 'off';
            app.ThicknessLabelValue.Visible = 'off';
            app.SimulateButton.Enable = 'off';
            app.SimulateDesigninLumericalLabel.Enable = 'off';
            app.PlotElectricFieldLabel.Enable = 'off';
            app.PlotButton.Enable = 'off';
            app.IndexEditField.Enable = 'off';
            app.IndexEditFieldLabel.Enable = 'off';
            app.MaterialLabel.Text = 'Material Property (ω or n)';
        end

        % Button pushed function: ImportGeneratorButton
        function ImportGeneratorButtonPushed(app, event)
            [app.file_netG,app.path_netG] = uigetfile('*.pt');
            if app.file_netG==0
                % user pressed cancel
                return
            else
                app.PassTargetintoGeneratorLabel.Enable = 'on';
                app.GenerateDesignButton.Enable = 'on';
                app.genimported = 1;
            end
            drawnow;
        end

        % Button pushed function: GenerateDesignButton
        function GenerateDesignButtonPushed(app, event)
        if ispref('ganapppref')
            d = uiprogressdlg(app.ML_Photonics_Designer,'Title','Please Wait','Indeterminate','On');
                if ~exist(strcat(app.folder_path,'Temp'), 'dir')
                   mkdir(strcat(app.folder_path,'Temp'))
                end         
           
                if strcmp(app.Switch.Value,'Import Excel')==1
                    app.datafile = fullfile(app.path_csv,app.file_csv);
                    app.datafile = strrep(app.datafile, '\', '/');
                else
                    writematrix(app.UITable.Data,strcat(app.folder_path,'Temp/tempdata.csv'));
                    app.datafile = strcat(app.folder_path,'Temp/tempdata.csv');
                end
                
                % Build Python File
                py0 = sprintf("from __future__ import print_function\nimport torch\nimport torch.nn as nn\nimport torch.nn.parallel\n\n\nclass Generator(nn.Module):\n\tdef __init__(self, ngpu):\n\t\tsuper(Generator, self).__init__()\n\t\tself.ngpu = ngpu\n\n\t\tself.conv1 = nn.ConvTranspose2d(nz, ngf * 8, 6, 1, 0, bias=False)\n\t\tself.conv2 = nn.BatchNorm2d(ngf * 8)\n\t\tself.conv3 = nn.ReLU(True)\n\t\t# state size. (ngf*8) x 6 x 6\n\t\tself.conv4 = nn.ConvTranspose2d(ngf * 8, ngf * 4, 6, 2, 2, bias=False)\n\t\tself.conv5 = nn.BatchNorm2d(ngf * 4)\n\t\tself.conv6 = nn.ReLU(True)\n\t\t# state s7ze. (ngf*4) x 12 x 12\n\t\tself.conv7 = nn.ConvTranspose2d(ngf * 4, ngf * 2, 6, 2, 4, bias=False)\n\t\tself.conv8 = nn.BatchNorm2d(ngf * 2)\n\t\tself.conv9 = nn.ReLU(True)\n\t\t# state size. (ngf*2) x 20 x 20\n\t\tself.conv10 = nn.ConvTranspose2d(ngf * 2, ngf, 6, 2, 5, bias=False)\n\t\tself.conv11 = nn.BatchNorm2d(ngf)\n\t\tself.conv12 = nn.ReLU(True)\n\t\t# state size. (ngf) x 34 x 34\n\t\tself.conv13 = nn.ConvTranspose2d(ngf, nc, 6, 2, 4, bias=False)\n\t\tself.conv14 = nn.Tanh()\n\t\t# state size. (nc) x 64 x 64\n\t#        )\n\n\tdef forward(self, input):\n\t\timageOut = input\n\t\timageOut = self.conv1(imageOut)\n\t\timageOut = self.conv2(imageOut)\n\t\timageOut = self.conv3(imageOut)\n\t\timageOut = self.conv4(imageOut)\n\t\timageOut = self.conv5(imageOut)\n\t\timageOut = self.conv6(imageOut)\n\t\timageOut = self.conv7(imageOut)\n\t\timageOut = self.conv8(imageOut)\n\t\timageOut = self.conv9(imageOut)\n\t\timageOut = self.conv10(imageOut)\n\t\timageOut = self.conv11(imageOut)\n\t\timageOut = self.conv12(imageOut)\n\t\timageOut = self.conv13(imageOut)\n\t\timageOut = self.conv14(imageOut)\n\n\t\treturn imageOut");
            
                % Save to File
                file = fopen(strcat(app.folder_path,'/Temp/GeneratorDef.py'),'w');
                fprintf(file,'%s',py0);
                fclose(file);
                
                
                % Build Python File
                py1 = sprintf('from __future__ import print_function \nimport scipy \nimport scipy.ndimage\nimport torch \nimport torch.nn.parallel \nimport torch.utils.data \nimport matplotlib.pyplot as plt \nimport pandas as pd \nimport cv2 \nfrom GeneratorDef import Generator\n\n');
                py2 = sprintf("netGDir='%s'\n\n",fullfile(app.path_netG,app.file_netG));
                py2 = strrep(py2, '\', '/');
                py3 = sprintf("excelTestData = pd.read_csv('%s', header=None).to_numpy() \n\n", app.datafile);
                py4 = sprintf("netG = torch.load(netGDir, map_location='cpu') \n\nexcelDataSpectra = excelTestData[:800,1] \nexcelTestDataTensor = torch.tensor(excelDataSpectra).type(torch.FloatTensor) \ntestTensor = torch.Tensor() \n\n\ntensor1 = torch.cat((excelTestDataTensor,torch.rand(400))) \ntensor2 = tensor1.unsqueeze(1).unsqueeze(1).unsqueeze(1) \ntensor3 = tensor2.permute(1,0,2,3) \ntestTensor = torch.cat((testTensor,tensor3),0) \n\nfake = netG(testTensor).detach().cpu() \nimg_reshape = fake.permute(0,2,3,1) \nimg = img_reshape.squeeze() \nimg = img.numpy() \nimg = (img + 1)/2 \n\n\n#CALCULATE PROPERTIES \nim_size = 64 \npmax = 4.0 \ntmax = 10.0 \nemax = 5.0 \npsum = 0.0 \npnum = 0.0 \ntsum = 0.0 \ntnum = 0.0 \nesum = 0.0 \nenum = 0.0 \n\nfor row in range(im_size): \n\tfor col in range(im_size): \n\t\tif img[row][col][0] > 0.2 or img[row][col][1] > 0.2: \n\t\t\tif img[row][col][0] > img[row][col][1]: \n\t\t\t\tpsum += img[row][col][0] \n\t\t\t\tpnum += 1 \n\t\t\telse: \n\t\t\t\tesum += img[row][col][1] \n\t\t\t\tenum += 1 \n\t\telse: \n\t\t\ttsum += img[row][col][2] \n\t\t\ttnum += 1 \nif pnum > 0: \n\tpAvg = psum / pnum \nelse: \n\tpAvg = 0.0 \n\nif tnum > 0: \n\ttAvg = tsum / tnum \nelse: \n\ttAvg = 0.0 \n\nif enum > 0: \n\teAvg = esum / enum \nelse: \n\teAvg = 0.0 \n\n\npfake = pmax * pAvg \ntfake = tmax * tAvg \nefake = emax * eAvg \n\n\nif pnum > enum: \n\tpindexfake = pfake \n\tclassifier = 0 \nelse: \n\tpindexfake = efake \n\tclassifier = 1 \n\nplt.imsave('%sTemp/test.png',img) \n\nfile = open('%sTemp/tempproperties.txt','w') \nfile.write(str(pindexfake) + '\\n' + str(tfake) + '\\n' + str(classifier)) \nfile.close()\n\nim_size = 64\nrgb = img\nfor row in range(im_size):\n\tfor col in range(im_size):\n\t\tif rgb[row][col][0] > rgb[row][col][2]:\n\t\t\trgb[row][col][0] = 0\n\t\t\trgb[row][col][1] = 0\n\t\t\trgb[row][col][2] = 0\n\t\telse:\n\t\t\trgb[row][col][0] = 1\n\t\t\trgb[row][col][1] = 1\n\t\t\trgb[row][col][2] = 1\n\ngray = cv2.cvtColor(rgb, cv2.COLOR_BGR2GRAY)\ncv2.normalize(gray, gray, -1, 1, cv2.NORM_MINMAX)\n\n#GAUSSIAN FILTER\nimg_filter = scipy.ndimage.gaussian_filter(gray,sigma=0.75)\nret, img_filter = cv2.threshold(img_filter,0.1,1,cv2.THRESH_BINARY) # 0 = black, 1 = white; everything under first number to black\n\nplt.imsave('%sTemp/gray.png', img_filter, cmap = 'gray')", app.folder_path, app.folder_path, app.folder_path);
                
                % Save to File
                file = fopen(strcat(app.folder_path,'/Temp/netG_Test.py'),'w');
                fprintf(file,'%s%s%s%s',py1, py2, py3, py4);
                fclose(file);
                
                % Run Python File
                command = [app.py_path ' ' app.folder_path '/Temp/netG_Test.py'];
                system(command);
                
                app.rgbImage = imread(strcat(app.folder_path,'Temp/gray.png'));
                         
                app.ImageGAN.ImageSource = rot90(app.rgbImage);
                app.ImageGAN.Visible = 'on';
                app.Image.Visible = 'off';
                
                fid = fopen(strcat(app.folder_path,'/Temp/tempproperties.txt'), 'r');
                app.props = fscanf(fid, '%f');
                fclose(fid);
                
                app.MaterialLabelValue.Value = app.props(1);
                app.ThicknessLabelValue.Value = app.props(2)*100;
                if app.props(3) == 0
                    app.MaterialLabel.Text = 'Material Property (ω)';
                else
                    app.MaterialLabel.Text = 'Material Property (n)';
                end
                
                app.MaterialLabel.Visible = 'on';
                app.MaterialLabelValue.Visible = 'on';
                app.ThicknessnmLabel.Visible = 'on';
                app.ThicknessLabelValue.Visible = 'on';
                
%                 delete(strcat(app.folder_path,'GeneratorDef.py'));
%                 delete(strcat(app.folder_path,'netG_Test.py'));
%                 delete(strcat(app.folder_path,'test.png'));
%                 delete(strcat(app.folder_path,'tempproperties.txt'));
%                 delete(strcat(app.folder_path,'tempdata.csv'));
%                 delete(strcat(app.folder_path,'gray.png'));

            close(d);
            
            app.SimulateButton.Enable = 'on';
            app.SimulateDesigninLumericalLabel.Enable = 'on';
            
            app.PlotElectricFieldLabel.Enable = 'on';
            app.PlotButton.Enable = 'on';
            app.IndexEditField.Enable = 'on';
            app.IndexEditFieldLabel.Enable = 'on';
        else
            f = errordlg('Preferences are not set.','Application Error');
        end
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            d = uiprogressdlg(app.ML_Photonics_Designer,'Title','Please Wait','Indeterminate','On');
                %imwrite(app.rgbImage, strcat(app.folder_path,'/Temp/gray.png'));
            
                % Build Python File
                py1 = sprintf("#Performs image to binary file conversion for image import in Lumerical\nimport torch\nimport numpy as np\nimport cv2\nimport os\nimport glob\n\n\nimgs = cv2.imread('%sTemp/gray.png')\nimgs = np.float32(imgs)\nimgs = cv2.cvtColor(imgs, cv2.COLOR_BGR2GRAY)\n\n# Turn image array into binary array (black to 1, white to 0)\nbinary = np.zeros(shape = (np.shape(imgs)[0], np.shape(imgs)[1]), dtype = np.uint8)\nimg = imgs[:][:]\nbinary[img[:][:] <= 50] = 1\n\n# doubling the amount of pixels in both dimensions\nresize_fac = 2\nheight, width = imgs.shape\nnew = np.ones(shape = (resize_fac*height, resize_fac*width), dtype = np.uint8)\nnew[:binary.shape[0],:binary.shape[1]] = binary[:][:]\nfor i in range(height-1,-1,-1):\n\tfor j in range(width-1,-1,-1):\n\t\tcur = new[i][j]\n\t\tnew[resize_fac*i:resize_fac*(i+1),resize_fac*j:resize_fac*(j+1)] = [[cur]*resize_fac] * resize_fac\n\nfile1 = open('%sTemp/gray.txt', 'w')\nheader = str(height*resize_fac)+' 1 '+str(height*resize_fac)+' \\n'+str(width*resize_fac)+' 1 '+str(width*resize_fac)+' \\n2 1 2\\n' \nfile1.write(header)\nbody = new.reshape(new.shape[0]*new.shape[1],1)\ncnt=0\nfor item in body:\n\tfile1.write('%ss\\n' %s item[0])\n\tcnt +=1\nfor item in body:\n\tfile1.write('%ss\\n' %s item[0])\n\nfile1.close()", app.folder_path, app.folder_path, '%', '%', '%', '%');
            
                % Save to File
                file = fopen(strcat(app.folder_path,'Temp/img2bin.py'),'w');
                fprintf(file,'%s',py1);
                fclose(file);
                
                % Run Python File
                command = [app.py_path ' ' app.folder_path '/Temp/img2bin.py'];
                system(command);
                
%                 delete(strcat(app.folder_path,'img2bin.py'));
%                 delete(strcat(app.folder_path,'gray.png'));
                
                
                % Build Lumerical File
                py1 = sprintf("file = '%sTemp/gray.txt';", app.folder_path);
                py2 = sprintf("\np = %f;",app.props(1));
                py3 = sprintf("\nt = %f;", app.props(2));
                py4 = sprintf("\nclass = %d;", app.props(3));
                py5 = sprintf("\n\nif(class == 0){\n\tload('%sMaster.fsp');\n\tswitchtolayout;\n\taddimport;\n\timportbinary(file, 'microns');  # load file\n\n\tif (p < 2.1155){\n\t\tm = 'Au (Gold) - Palik Copy 1';\n\t}\n\telse if (p > 2.9455){\n\t\tm = 'Al (Aluminium) - CRC';\n\t}\n\telse{\n\t\tm = 'Ag (Silver) - CRC';\n\t}\n\tselect('import');\n\tset('material', m); \n\tset('x span', 3.2e-6); \n\tset('y span', 3.2e-6); \n\tset('z span', 0.1e-6); \n\tset('x', 0); \n\tset('y', 0); \n\tset('z', 0.05e-6);\n\n\n\tselectpartial('substrate::spacer');\n\tset('z min',-t*1e-7);\n\tselectpartial('substrate::mirror');\n\tset('z max',-t*1e-7);\n\tset('z min',(-t-2)*1e-7);\n\tselectpartial('FDTD');\n\tset('z min',(-t-2)*1e-7);\n}\nelse{\n\tload('%sMaster(DielectricMetal).fsp');\n\tswitchtolayout;\n\taddimport;\n\timportbinary(file, 'microns');  # load file\n\n\tif (p < 2.915){\n\t\tm = 'ZnSe';\n\t}\n\telse if (p > 3.715){\n\t\tm = 'Ge (Germanium) - Palik';\n\t}\n\telse{\n\t\tm = 'Si (Silicon) - Palik';\n\t}\n\n\tselect('import');\n\n\tset('material', m); \n\tset('x span', 7.5e-6); \n\tset('y span', 7.5e-6); \n\tset('z min', -0.2e-6); \n\tset('z max', (-2+t)*1e-7);\n\tset('x', 0); \n\tset('y', 0); \n\tset('z', 0.05e-6);\n}\n\n\nsave('Temp/gray.fsp');\n\nselect('import');", app.folder_path, app.folder_path);
                
                % Save to File
                file = fopen(strcat(app.folder_path,'Temp/importgray.lsf'),'w');
                fprintf(file,'%s%s%s%s%s',py1, py2, py3, py4, py5);
                fclose(file);
                
                path(path,app.api_path); 
                h=appopen('fdtd');
                lum_working_path = char(sprintf("addpath('%sTemp');",app.folder_path));
                appevalscript(h,lum_working_path);                
                appevalscript(h,'importgray;');
                appevalscript(h,'run;');
                appevalscript(h,'save;');
                appevalscript(h,'freq = getresult("mon_R","f");');
                appevalscript(h,'lambda = 1e9*3e8/freq;');
                appevalscript(h,'monR = getresult("mon_R","T"); Absorption = 1-monR.T;');
                appevalscript(h,sprintf('matlabsave("%sTemp/gray-Excel.mat", lambda,Absorption);', app.folder_path));
                
                appevalscript(h,'electric = getelectric("monitor_fields");');
                appevalscript(h,sprintf('matlabsave("%sTemp/gray-Field.mat", electric);', app.folder_path));
                %appclose(h);
                
                hold(app.UIAxes, 'on');
                app.sim_absorption = load(strcat(app.folder_path,'Temp/gray-Excel.mat'),'Absorption').Absorption.';
                plot(app.UIAxes,app.x,app.sim_absorption,'LineWidth',3);
                legend(app.UIAxes, 'Target', 'Generated');
                hold(app.UIAxes, 'off');
                
%                 delete(strcat(app.folder_path,'gray.txt'));
%                 delete(strcat(app.folder_path,'importgray.lsf'));
%                 delete(strcat(app.folder_path,'gray_p0.log'));
            close(d);
            
            app.PlotElectricFieldLabel.Enable = 'on';
            app.PlotButton.Enable = 'on';
            app.IndexEditField.Enable = 'on';
            app.IndexEditFieldLabel.Enable = 'on';
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            cla(app.UIAxes)

            app.sim_absorption = load(strcat(app.folder_path,'Temp/gray-Excel.mat'),'Absorption').Absorption.';
            app.index = app.IndexEditField.Value;
            app.electric = load(strcat(app.folder_path,'Temp/gray-Field.mat')).electric;
            
            slice = app.electric(:,:,:,app.index);
            imagesc(app.UIAxes3, rot90(slice));
            %colorcet('rainbow');
            %axis square;
            colorbar(app.UIAxes3);
            app.UIAxes3.TickLength = [0 0];
            app.UIAxes3.YAxis.TickLength = [0 0];
            clim_max = max(max(slice))/4;
            set(app.UIAxes3, 'CLim', [0 clim_max]);
            xlim(app.UIAxes3, [1 160]);
            ylim(app.UIAxes3, [1 160]);
            
            app.Image2.Visible = 'off';
            app.UIAxes3.Visible = 'on';
            
            hold(app.UIAxes, 'on');            
            x_plot=4:0.08:11.99;
            plot(app.UIAxes,app.x,app.y,'LineWidth',3);
            plot(app.UIAxes,app.x,app.sim_absorption,'LineWidth',3);
            plot(app.UIAxes,ones(1,10)*x_plot(app.index),linspace(0,1,10),'--','LineWidth',2, 'color','red');
            legend(app.UIAxes, 'Target', 'Generated','E-Field Index');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ML_Photonics_Designer = uifigure('Visible', 'off');
            app.ML_Photonics_Designer.Name = 'ML_Photonics_Designer';
            app.ML_Photonics_Designer.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ML_Photonics_Designer);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.DefineTargetTab = uitab(app.TabGroup);
            app.DefineTargetTab.Title = '(1) Define Target';
            app.GridLayout2 = uigridlayout(app.DefineTargetTab);
            app.ImportSpectrumButton = uibutton(app.GridLayout2, 'push');
            app.ImportSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSpectrumButtonPushed, true);
            app.ImportSpectrumButton.Tooltip = {'Import Excel file of 800 x 2 (row x column) format.'};
            app.ImportSpectrumButton.Text = 'Import Spectrum ';
            app.TargetDefinitionOptionsLabel = uilabel(app.GridLayout2);
            app.TargetDefinitionOptionsLabel.Text = 'Target Definition Options';
            app.Switch = uiswitch(app.GridLayout2, 'slider');
            app.Switch.Items = {'Lorentz. Calc.', 'Import Excel'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Import Excel';
            app.Image_Lorentzian = uiimage(app.GridLayout2);
            app.Image_Lorentzian.ImageSource = 'Lorentzian.png';
            app.ImportSpectrumfromExcelLabel = uilabel(app.GridLayout2);
            app.ImportSpectrumfromExcelLabel.Text = 'Import Spectrum from Excel';
            app.LorentzianCalculatorLabel = uilabel(app.GridLayout2);
            app.LorentzianCalculatorLabel.Text = 'Lorentzian Calculator';
            app.FWHMEditFieldLabel = uilabel(app.GridLayout2);
            app.FWHMEditFieldLabel.Text = 'FWHM (Γ):';
            app.FWHMEditField = uieditfield(app.GridLayout2, 'numeric');
            app.FWHMEditField.Limits = [0.65 1];
            app.FWHMEditField.ValueChangedFcn = createCallbackFcn(app, @FWHMEditFieldValueChanged, true);
            app.FWHMEditField.Value = 0.75;
            app.CenterX0EditFieldLabel = uilabel(app.GridLayout2);
            app.CenterX0EditFieldLabel.Text = 'Center (X0):';
            app.CenterX0EditField = uieditfield(app.GridLayout2, 'numeric');
            app.CenterX0EditField.Limits = [4 12];
            app.CenterX0EditField.ValueChangedFcn = createCallbackFcn(app, @CenterX0EditFieldValueChanged, true);
            app.CenterX0EditField.Value = 6;
            app.GenerateDesignTab = uitab(app.TabGroup);
            app.GenerateDesignTab.Title = '(2) Generate Design';
            app.GridLayout3 = uigridlayout(app.GenerateDesignTab);
            app.GenerateDesignButton = uibutton(app.GridLayout3, 'push');
            app.GenerateDesignButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateDesignButtonPushed, true);
            app.GenerateDesignButton.Tooltip = {'Generate predicted image.'};
            app.GenerateDesignButton.Text = 'Generate Design';
            app.ImportGeneratorButton = uibutton(app.GridLayout3, 'push');
            app.ImportGeneratorButton.ButtonPushedFcn = createCallbackFcn(app, @ImportGeneratorButtonPushed, true);
            app.ImportGeneratorButton.Tooltip = {'Import generator file  (*.pt format).'};
            app.ImportGeneratorButton.Text = 'Import Generator';
            app.GenerationandSimulateDesignLabel = uilabel(app.GridLayout3);
            app.GenerationandSimulateDesignLabel.Text = 'Generation and Simulate Design';
            app.SetGeneratorPathLabel = uilabel(app.GridLayout3);
            app.SetGeneratorPathLabel.Text = '(1) Set Generator Path';
            app.PassTargetintoGeneratorLabel = uilabel(app.GridLayout3);
            app.PassTargetintoGeneratorLabel.Text = '(2) Pass Target into Generator';
            app.SimulateDesigninLumericalLabel = uilabel(app.GridLayout3);
            app.SimulateDesigninLumericalLabel.Text = '(3) Simulate Design in Lumerical';
            app.SimulateButton = uibutton(app.GridLayout3, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Tooltip = {'Simulate Design in Lumerical.'};
            app.SimulateButton.Text = 'Simulate';
            app.PlotElectricFieldLabel = uilabel(app.GridLayout3);
            app.PlotElectricFieldLabel.Text = '(4) Plot Electric Field';
            app.PlotButton = uibutton(app.GridLayout3, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Tooltip = {'Plot Electric Field Profile'};
            app.PlotButton.Text = 'Plot';
            app.IndexEditFieldLabel = uilabel(app.GridLayout3);
            app.IndexEditFieldLabel.Text = 'Index:';
            app.IndexEditField = uieditfield(app.GridLayout3, 'numeric');
            app.IndexEditField.Limits = [1 100];
            app.IndexEditField.Value = 1;
            app.OptimizeTab = uitab(app.TabGroup);
            app.OptimizeTab.Title = '(3) Optimize';
            app.GridLayout4 = uigridlayout(app.OptimizeTab);
            app.AdjointOptimizationLabel = uilabel(app.GridLayout4);
            app.AdjointOptimizationLabel.Text = 'Adjoint Optimization';
            app.TargetmEditFieldLabel = uilabel(app.GridLayout4);
            app.TargetmEditFieldLabel.Text = 'Target (μm):';
            app.TargetmEditField = uieditfield(app.GridLayout4, 'numeric');
            app.TargetmEditField.Limits = [4 12];
            app.TargetmEditField.Value = 4;
            app.IterationsEditFieldLabel = uilabel(app.GridLayout4);
            app.IterationsEditFieldLabel.Text = 'Iterations:';
            app.IterationsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.Polarizations1p2EditFieldLabel = uilabel(app.GridLayout4);
            app.Polarizations1p2EditFieldLabel.Text = 'Polarization (s = 1, p = 2):';
            app.Polarizations1p2EditField = uieditfield(app.GridLayout4, 'numeric');
            app.Polarizations1p2EditField.Limits = [1 2];
            app.Polarizations1p2EditField.Value = 1;
            app.RunButton = uibutton(app.GridLayout4, 'push');
            app.RunButton.Text = 'Run';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout1 = uigridlayout(app.RightPanel);
            app.UIAxes3 = uiaxes(app.GridLayout1);
            app.UIAxes3.Visible = 'off';
            app.UIAxes = uiaxes(app.GridLayout1);
            xlabel(app.UIAxes, 'Wavelength (µm)')
            ylabel(app.UIAxes, 'Absorption')
            app.UIAxes.XLim = [4 12];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XTick = [4 6 8 10 12];
            app.UIAxes.XTickLabel = {'4'; '6'; '8'; '10'; '12'};
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.Image2 = uiimage(app.GridLayout1);
            app.Image2.ImageSource = 'EM_Simulation.PNG';
            app.EMFieldProfileLabel = uilabel(app.GridLayout1);
            app.EMFieldProfileLabel.Text = 'EM Field Profile';
            app.UITable = uitable(app.GridLayout1);
            app.UITable.ColumnName = {'Wavelength (um)'; 'Absorption'};
            app.UITable.RowName = {};
            app.MLGeneratedStructureLabel = uilabel(app.GridLayout1);
            app.MLGeneratedStructureLabel.Text = 'ML-Generated Structure';
            app.Image = uiimage(app.GridLayout1);
            app.Image.ImageSource = 'ML-Generated Structure.PNG';
            app.TargetSpectrumPointsLabel = uilabel(app.GridLayout1);
            app.TargetSpectrumPointsLabel.Tooltip = {'NOTE: Updating the table will only update the Target Spectrum Plot.'};
            app.TargetSpectrumPointsLabel.Text = 'Target Spectrum Points*';
            app.TargetSpectrumPlotLabel = uilabel(app.GridLayout1);
            app.TargetSpectrumPlotLabel.Text = 'Target Spectrum Plot';
            app.ImageGAN = uiimage(app.GridLayout1);
            app.ImageGAN.Visible = 'off';
            app.MaterialLabel = uilabel(app.GridLayout1);
            app.MaterialLabel.Visible = 'off';
            app.MaterialLabel.Text = 'Material Property (ω or n)';
            app.ThicknessnmLabel = uilabel(app.GridLayout1);
            app.ThicknessnmLabel.Visible = 'off';
            app.ThicknessnmLabel.Text = 'Thickness (nm)';
            app.MaterialLabelValue = uieditfield(app.GridLayout1, 'numeric');
            app.MaterialLabelValue.Editable = 'off';
            app.MaterialLabelValue.Visible = 'off';
            app.ThicknessLabelValue = uieditfield(app.GridLayout1, 'numeric');
            app.ThicknessLabelValue.Editable = 'off';
            app.ThicknessLabelValue.Visible = 'off';
            app.PreferencesMenu = uimenu(app.ML_Photonics_Designer);
            app.PreferencesMenu.Text = 'Preferences';
            app.SetPythonPathMenu = uimenu(app.PreferencesMenu);
            app.SetPythonPathMenu.MenuSelectedFcn = createCallbackFcn(app, @SetPythonPathMenuSelected, true);
            app.SetPythonPathMenu.Text = 'Set Python Path';
            app.SetLumericalWorkingDirectoryMenu = uimenu(app.PreferencesMenu);
            app.SetLumericalWorkingDirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @SetLumericalWorkingDirectoryMenuSelected, true);
            app.SetLumericalWorkingDirectoryMenu.Text = 'Set Lumerical Working Directory';
            app.SetAPIPathMenu = uimenu(app.PreferencesMenu);
            app.SetAPIPathMenu.MenuSelectedFcn = createCallbackFcn(app, @SetAPIPathMenuSelected, true);
            app.SetAPIPathMenu.Text = 'Set API Path';
            app.HelpDocumentationMenu = uimenu(app.PreferencesMenu);
            app.HelpDocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpDocumentationMenuSelected, true);
            app.HelpDocumentationMenu.Text = 'Help Documentation';
            % Show the figure after all components are created
            app.ML_Photonics_Designer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ML_Opt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ML_Photonics_Designer)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ML_Photonics_Designer)
        end
    end
end